classdef Septica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        IacartileButton_2  matlab.ui.control.Button
        IacartileButton    matlab.ui.control.Button
        Label_2            matlab.ui.control.Label
        TurnLabel          matlab.ui.control.Label
        CartiramaseLabel   matlab.ui.control.Label
        Label              matlab.ui.control.Label
        ERRLabel           matlab.ui.control.Label
        UITable3           matlab.ui.control.Table
        UITable2           matlab.ui.control.Table
        UITable            matlab.ui.control.Table
    end

    
    properties (Access = private)
        Deck
        DeckSize
        Player1Deck
        Player1DeckSize
        Player2Deck
        Player2DeckSize
        TableDeck
        TableDeckSize
        Player1WinDeck
        Player1WinDeckSize
        Player2WinDeck
        Player2WinDeckSize
        Turn
    end
    
    methods (Access = public)
        
        function createDeck(app)
            app.Deck = struct('Simbol', {}, 'Numar', {});

            for i = 7:14
                app.Deck(end + 1) = struct('Simbol', 'Rosu', 'Numar', i);
            end

             for i = 7:14
                app.Deck(end + 1) = struct('Simbol', 'Negru', 'Numar', i);
             end

              for i = 7:14
                app.Deck(end + 1) = struct('Simbol', 'Romb', 'Numar', i);
              end

               for i = 7:14
                app.Deck(end + 1) = struct('Simbol', 'Trefla', 'Numar', i);
               end
        end

        function mixDeck(app)
            app.DeckSize = 32;

            index = randperm(32);
            app.Deck = app.Deck(index);
        end

        function startGame(app)
            app.Player1Deck = struct('Simbol', {}, 'Numar', {});
            app.Player2Deck = struct('Simbol', {}, 'Numar', {});
            app.TableDeck = struct('Simbol', {}, 'Numar', {});
            app.Player1WinDeck = struct('Simbol', {}, 'Numar', {});
            app.Player2WinDeck = struct('Simbol', {}, 'Numar', {});
            app.Player1DeckSize = 4;
            app.Player2DeckSize = 4;
            app.TableDeckSize = 0;
            app.Player1WinDeckSize = 0;
            app.Player2WinDeckSize = 0;
            app.Turn = 1;

            for i = 1 : 4
                app.Player1Deck(i) = app.Deck(app.DeckSize);
                app.DeckSize = app.DeckSize - 1;
            end

            for i = 1 : 4
                app.Player2Deck(i) = app.Deck(app.DeckSize);
                app.DeckSize = app.DeckSize - 1;
            end

            populatePlayer1Table(app);
            populatePlayer2Table(app);
        end

        function populatePlayer1Table(app)
            data = cell(app.Player1DeckSize, 2);

            for i = 1 : app.Player1DeckSize
                data{i, 1} = app.Player1Deck(i).Simbol;
                data{i, 2} = app.Player1Deck(i).Numar;
            end

            app.UITable.Data = data;
        end

        function populatePlayer2Table(app)
            data = cell(app.Player1DeckSize, 2);

            for i = 1 : app.Player2DeckSize
                data{i, 1} = app.Player2Deck(i).Simbol;
                data{i, 2} = app.Player2Deck(i).Numar;
            end

            app.UITable3.Data = data;
        end

        function populateTableTable(app)
            data = cell(app.TableDeckSize, 2);

            for i = 1 : app.TableDeckSize
                data{i, 1} = app.TableDeck(i).Simbol;
                data{i, 2} = app.TableDeck(i).Numar;
            end

            app.UITable2.Data = data;
        end
        
        function takeCards(app, player)
            if player == 1
                while app.TableDeckSize > 0
                    app.Player1WinDeck(end + 1) = app.TableDeck(1);
                    app.Player1WinDeckSize = app.Player1WinDeckSize + 1;
                    app.TableDeck(1) = [];
                    app.TableDeckSize = app.TableDeckSize - 1;
                end
            else
                while app.TableDeckSize > 0
                    app.Player2WinDeck(end + 1) = app.TableDeck(1);
                    app.Player2WinDeckSize = app.Player2WinDeckSize + 1;
                    app.TableDeck(1) = [];
                    app.TableDeckSize = app.TableDeckSize - 1;
                end
            end

            populateTableTable(app);
            dealCards(app);

            if player == 1
                app.Turn = 1;
            else
                app.Turn = 2;
            end
            turn(app);
        end

        function Cards(app)
            app.Label.Text = num2str(app.DeckSize);
        end

        function turn(app)
            if app.Turn == 1
                app.TurnLabel.Text = "Randul jucatorului 1";
            else
                app.TurnLabel.Text = "Randul jucatorului 2";
            end
        end

        function dealCards(app)
            while app.DeckSize > 0                

                    if app.Player1DeckSize < 4
                        app.Player1Deck(end + 1) = app.Deck(app.DeckSize);
                        app.Player1DeckSize = app.Player1DeckSize + 1;
                        app.DeckSize = app.DeckSize - 1;
                    end
    
                    if app.DeckSize == 0
                        break;
                    end

                    if app.Player2DeckSize < 4
                        app.Player2Deck(end + 1) = app.Deck(app.DeckSize);
                        app.Player2DeckSize = app.Player2DeckSize + 1;
                        app.DeckSize = app.DeckSize - 1;
                    end

                if app.Player1DeckSize == 4 && app.Player2DeckSize == 4
                        break;
                    end
            end

            Cards(app);
            populatePlayer1Table(app);
            populatePlayer2Table(app);
        end

        function checkEnd(app)
            if app.Player1DeckSize == 0 && app.Player2DeckSize == 0
                sum1 = 0;
                sum2 = 0;

                for i = 1 : app.Player1WinDeckSize
                    if app.Player1WinDeck(i).Numar == 10 || app.Player1WinDeck(i).Numar == 11
                        sum1 = sum1 + 1;
                    end
                end

                for i = 1 : app.Player2WinDeckSize
                    if app.Player2WinDeck(i).Numar == 10 || app.Player2WinDeck(i).Numar == 11
                        sum2 = sum2 + 1;
                    end
                end
            

                if sum1 > sum2
                    app.Label_2.Text = ['Jucatorul 1 castiga cu ', num2str(sum1), ' puncte'];
                elseif sum2 > sum1
                    app.Label_2.Text = ['Jucatorul 2 castiga cu ', num2str(sum2), ' puncte'];
                else
                    app.Label_2.Text = 'Remiza';
                end
                pause(5);
                endGame(app);
            end
        end

        function endGame(app)
            app.Label_2.Text = "";
            app.Deck = [];
            app.DeckSize = 0;
            app.Player1Deck = [];
            app.Player1DeckSize = 0;
            app.Player2Deck = [];
            app.Player2DeckSize = 0;
            app.TableDeck = [];
            app.TableDeckSize = 0;
            app.Player1WinDeck = [];
            app.Player1WinDeckSize = 0;
            app.Player2WinDeck = [];
            app.Player2WinDeckSize = 0;

            startupFcn(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ERRLabel.Text = "";
            
            createDeck(app);
            mixDeck(app);
            startGame(app);
            Cards(app);
            turn(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            selection = event.Indices;
            
            if app.Turn == 1
                app.ERRLabel.Text = "";
                app.Turn = 2;
                turn(app);

                selection = selection(1, 1);
                app.TableDeck(end + 1) = app.Player1Deck(selection);
                app.TableDeckSize = app.TableDeckSize + 1;
                app.Player1DeckSize = app.Player1DeckSize - 1;
                app.Player1Deck(selection) = [];

                populateTableTable(app);
                populatePlayer1Table(app);

            else
                app.ERRLabel.Text = "Nu este randul tau";
            end

        end

        % Cell selection callback: UITable3
        function UITable3CellSelection(app, event)
            selection = event.Indices;
            
            if app.Turn == 2
                app.ERRLabel.Text = "";
                app.Turn = 1;
                turn(app);

                selection = selection(1, 1);
                app.TableDeck(end + 1) = app.Player2Deck(selection);
                app.TableDeckSize = app.TableDeckSize + 1;
                app.Player2DeckSize = app.Player2DeckSize - 1;
                app.Player2Deck(selection) = [];

                populateTableTable(app);
                populatePlayer2Table(app);
                
            else
                app.ERRLabel.Text = "Nu este randul tau";
            end
        end

        % Button pushed function: IacartileButton
        function IacartileButtonPushed(app, event)
            takeCards(app, 2);
            checkEnd(app);
        end

        % Button pushed function: IacartileButton_2
        function IacartileButton_2Pushed(app, event)
            takeCards(app, 1);
            checkEnd(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Numar'; 'Model'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Numar'; 'Model'};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'Numar'; 'Model'};
            app.UITable3.RowName = {};
            app.UITable3.CellSelectionCallback = createCallbackFcn(app, @UITable3CellSelection, true);
            app.ERRLabel = uilabel(app.UIFigure);
            app.ERRLabel.Text = 'ERR';
            app.Label = uilabel(app.UIFigure);
            app.CartiramaseLabel = uilabel(app.UIFigure);
            app.CartiramaseLabel.Text = 'Carti ramase:';
            app.TurnLabel = uilabel(app.UIFigure);
            app.TurnLabel.Text = 'Turn';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.IacartileButton = uibutton(app.UIFigure, 'push');
            app.IacartileButton.ButtonPushedFcn = createCallbackFcn(app, @IacartileButtonPushed, true);
            app.IacartileButton.Text = 'Ia cartile';
            app.IacartileButton_2 = uibutton(app.UIFigure, 'push');
            app.IacartileButton_2.ButtonPushedFcn = createCallbackFcn(app, @IacartileButton_2Pushed, true);
            app.IacartileButton_2.Text = 'Ia cartile';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Septica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end